classdef ModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AbioticTab                     matlab.ui.container.Tab
        ClimateScenarioButtonGroup     matlab.ui.container.ButtonGroup
        RCP26Button                    matlab.ui.control.RadioButton
        RCP45Button                    matlab.ui.control.RadioButton
        RCP60Button                    matlab.ui.control.RadioButton
        RCP85Button                    matlab.ui.control.RadioButton
        OceanAcidificationCheckBox     matlab.ui.control.CheckBox
        BioticTab                      matlab.ui.container.Tab
        EvolutionCheckBox              matlab.ui.control.CheckBox
        SuperSymbiontsButtonGroup      matlab.ui.container.ButtonGroup
        NoneButton                     matlab.ui.control.RadioButton
        OnButton                       matlab.ui.control.RadioButton
        SavelongmortalityyearsButton   matlab.ui.control.RadioButton
        SuperSymbiontoptionsButtonGroup  matlab.ui.container.ButtonGroup
        AddspecifiedadvantageButton    matlab.ui.control.RadioButton
        UsemeanofsuperInitYearsButton  matlab.ui.control.RadioButton
        UsemaxofsuperInitYearsButton   matlab.ui.control.RadioButton
        Addspecifiedadvantageatendoffirst5yearmortalityButton  matlab.ui.control.RadioButton
        SuperOptionPanel               matlab.ui.container.Panel
        AtyearEditFieldLabel           matlab.ui.control.Label
        AtyearEditField                matlab.ui.control.NumericEditField
        TemperatureadvantageEditFieldLabel  matlab.ui.control.Label
        TemperatureadvantageEditField  matlab.ui.control.NumericEditField
        ProgrammaticTab                matlab.ui.container.Tab
        EverynreefsEditFieldLabel      matlab.ui.control.Label
        EverynreefsEditField           matlab.ui.control.NumericEditField
        ReefSubsetDropDownLabel        matlab.ui.control.Label
        ReefSubsetDropDown             matlab.ui.control.DropDown
        KeyReefsTextAreaLabel          matlab.ui.control.Label
        KeyReefsTextArea               matlab.ui.control.TextArea
        RunevenifnotspecifiedaboveLabel  matlab.ui.control.Label
        WorkerthreadsSliderLabel       matlab.ui.control.Label
        WorkerthreadsSlider            matlab.ui.control.Slider
        OutputsTab                     matlab.ui.container.Tab
        GlobalMapsCheckBox             matlab.ui.control.CheckBox
        GlobalCoralCoverCheckBox       matlab.ui.control.CheckBox
        PanelPopulationPlotsButtonGroup  matlab.ui.container.ButtonGroup
        AllreefsButton                 matlab.ui.control.RadioButton
        KeyReefsButton                 matlab.ui.control.RadioButton
        OffButton                      matlab.ui.control.RadioButton
        MortalityandothertablesCheckBox  matlab.ui.control.CheckBox
        SavedataforcrossrunstressedreefplotsCheckBox  matlab.ui.control.CheckBox
        GenotypefigureCheckBox         matlab.ui.control.CheckBox
        GrowthratefigureCheckBox       matlab.ui.control.CheckBox
        RunTab                         matlab.ui.container.Tab
        GoButton_2                     matlab.ui.control.Button
        CheckSelectionsTextAreaLabel   matlab.ui.control.Label
        CheckSelectionsTextArea        matlab.ui.control.TextArea
        UIAxes                         matlab.ui.control.UIAxes
        JSONformatLabel                matlab.ui.control.Label
        SaveStateButton                matlab.ui.control.Button
    end


    properties (Access = private)
        modelVars % All variables which will be pass to the coral model.
        gaugeSet  % Store one gauge per worker when a run starts.
    end

    methods (Access = private)
    
        % Set key reefs vector from the text in the GUI text box.
        % Save that and all other modelVars to a file.
        function [jVars] = setKeySaveState(app)
            keyReefs = [];
           % Save the keyReefs field even if the current setting is "All Reefs"
            txt = app.KeyReefsTextArea.Value;
            txt = txt{1};  % get string out of cell
            if strcmp(txt, '')
                keyReefs = [];
                disp('empty text for keys');
                disp(keyReefs);
            else
                keyReefs = str2num(txt);
                if isempty(keyReefs)
                    error('Key reef list was not empty, but could not be understood.  Use only integers separated by spaces or commas.');
                else
                    [rows, ~] = size(keyReefs);
                    if rows > 1
                        error('Key reefs should be a row vector.  Do not use semicolons.');
                    end
                end
            end
 
            app.modelVars.keyReefs = keyReefs;
            jVars = jsonencode(app.modelVars);
            app.CheckSelectionsTextArea.Value = jVars;
            %% Save to a temp file for use on restart.
            pf = fopen('gui_scratch/modelVars.txt', 'w');
            fprintf(pf, '%s', jVars);
            fclose(pf);
            fprintf('Saved %s\n', jVars);
        end
        
        % Read all modelVars from a file (if it exists) and overwrite any built-in
        % defaults.
        function [] = recoverState(app)
            %% Recover values from a previous run, if possible.
            pf = fopen('gui_scratch/modelVars.txt', 'r');
            if pf ~= -1
                txt = fgetl(pf);
                fclose(pf);
                jVars = jsondecode(txt);
                disp(txt);
                % Overwrite any values from the file without deleting any set above.
                f = fieldnames(jVars);
                for i = 1:length(f)
                    app.modelVars.(f{i}) = jVars.(f{i});
                    fprintf('Updating %s \n', f{i});
                    disp(jVars.(f{i}));
                end
            end
        end
        
        % Update the visibility/editablity of the year and temperature advantage fields
        % base on current selections for other super symbionts.  This is called both after
        % user changes and during startup.
        function updateSuperFields(app)
            % These fields are a function only of whether super symbionts are enabled
            % and which option is chosen.
            yf = 'off';
            tf = 'off';
            if app.OnButton.Value
                selectedButton = app.SuperSymbiontoptionsButtonGroup.SelectedObject;
                txt = selectedButton.Text;
                switch txt
                    case 'Add specified advantage'
                        yf = 'on';
                        tf = 'on';
                    case 'Use mean of superInitYears'
                        yf = 'on';
                        tf = 'off';
                    case 'Use max of superInitYears'
                        yf = 'on';
                        tf = 'off';
                    case 'Add specified advantage at end of first 5-year mortality'
                        yf = 'off';
                        tf = 'on';
                    otherwise
                        error('Super sym selection %s is not supported in the GUI.', txt);
                end
            else
                yf = 'off';
                tf = 'off';
            end
            app.AtyearEditField.Enable = yf;
            app.TemperatureadvantageEditField.Enable = tf;
            if strcmp('off', yf) && strcmp('off', tf)
                app.SuperOptionPanel.Visible = 'off';
            else
                app.SuperOptionPanel.Visible = 'on';
            end
        end
        
        function setWorkerSlide(app)
            c = parcluster('local');
            nw = c.NumWorkers;
            app.WorkerthreadsSlider.Limits = [1 nw];
            app.WorkerthreadsSlider.MajorTicks = [1 2:2:nw];
            app.WorkerthreadsSlider.MinorTicks = [1:1:nw];
            % Default will give 1 less than the number of cores.
            % TODO: start with the last-used value.
            nc = feature('numcores');
            fprintf('nw %d, nc %d\n', nw, nc);
            
            app.WorkerthreadsSlider.Value = max(1, min(nw, nc-1));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Set worker slide based on the current machine.
            setWorkerSlide(app);
            
            
            %% Set default values.            
            % The variables to be passed to a script.  These startup defaults
            % will commonly be overwritten by recoverState below.
            app.modelVars.RCP = 'rcp85';
            app.modelVars.OA = 0;
            app.modelVars.E = 0;
            app.modelVars.everyx = 1;
            app.modelVars.useTestThreads = 5;
            app.modelVars.doProgressBar = true;
            app.modelVars.keyReefs = [];
            app.modelVars.superStart = 2035;
            app.modelVars.superMode = 0;
            app.modelVars.superAdvantage = 0.0;
            app.modelVars.newMortYears = false;
            app.modelVars.doCoralCoverFigure = false;
            % This one variable is not used directly by the model, but it keeps
            % the buttons in the right state on restart.
            app.modelVars.superSym = 'None';
            
            recoverState(app);
            
            
            %% Make controls match the default or restart values.
            % Abiotic defaults
            switch app.modelVars.RCP
                case 'rcp26'
                    app.RCP26Button.Value = true;
                case 'rcp45'
                    app.RCP45Button.Value = true;
                case 'rcp60'
                    app.RCP60Button.Value = true;
                case 'rcp85'
                    app.RCP85Button.Value = true;
                otherwise
                    error('GUI is not set up for rcp %s', app.modelVars.RCP);
            end
            app.OceanAcidificationCheckBox.Value = app.modelVars.OA;
            
            % Biotic defaults
            app.EvolutionCheckBox.Value = app.modelVars.E;
            % Start with symbiont options off or at lowest levels, so only changes from there
            % need to be coded explicitly:
            app.NoneButton.Value = true;
            app.AddspecifiedadvantageButton.Value = true;
            app.AtyearEditField.Value = app.modelVars.superStart;
            app.TemperatureadvantageEditField.Value = app.modelVars.superAdvantage;
            app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
            
            updateSuperFields(app);
            
            %% Programmatic defaults
            kr = sort(app.modelVars.keyReefs');
            charform = num2str(kr);            
            % Warning: if you convert with string(), you get an error saying
            % "'Value' must be a string or a vector cell array of strings" even
            % though the resulting variable tests as a string.
            app.KeyReefsTextArea.Value =  charform;
            
            % Note that the GUI is not 1:1 with the variables in the model.
            %{
            switch app.modelVars.superSym
                
                case 'None'
                    app.NoneButton.Value = true;
            %}
            % First the super symbiont addition mode, which can determine what else is in use.
            isFixedT = false;
            switch app.modelVars.superMode
                case 0
                    isFixedT = true;
                    if app.modelVars.superAdvantage == 0.0
                        % All set
                    else
                        app.OnButton.Value = true;
                        app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                    end
                case 1
                    app.UsemeanofsuperInitYearsButton.Value = true;
                    app.OnButton.Value = true;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                case 2
                    app.UsemaxofsuperInitYearsButton.Value = true;
                    app.OnButton.Value = true;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                case 6
                    isFixedT = true;
                    app.Addspecifiedadvantageatendoffirst5yearmortalityButton.Value = true;
                    app.OnButton.Value = true;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
                   
                otherwise
                    error('GUI is not set up for super symbiont mode %d', app.modelVars.superMode);
            end
            % Modes with a fixed temperature are the same as doing nothing if the advantage
            % value is zero.  Modes with variable temperatures don't use that value.  Modes with
            % computed times don't use "At year".
            if strcmp(app.modelVars.superSym, 'Save long mortality years')
                disp('in 2');
                app.SavelongmortalityyearsButton.Value = true;
                app.SuperSymbiontoptionsButtonGroup.Visible = 'off';           
            elseif strcmp(app.modelVars.superSym, 'None') || (app.modelVars.superAdvantage == 0.0 && isFixedT)
                disp('in 1');
                app.NoneButton.Value = true;
                app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
            
            else
                disp('in 3');
                app.OnButton.Value = true;
                app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            end
            updateSuperFields(app);
            
            
            % Output defaults
            app.GlobalMapsCheckBox.Value = true;
            app.GlobalCoralCoverCheckBox.Value = app.modelVars.doCoralCoverFigure;
            app.MortalityandothertablesCheckBox.Value = true;
            
                                    
            %% everyx replacement is inactive by default
            app.EverynreefsEditField.Enable = 'off';
            app.EverynreefsEditFieldLabel.Enable = 'off';
            
            %% GUI doesn't support tab reordering!  Kludge it here:
            %{
            Rebuilt the tab, but keep this as a sample.
            tabs = app.TabGroup.Children;
            ttab = tabs(5);
            tabs(5) = tabs(4);
            tabs(4) = ttab;
            app.TabGroup.Children = tabs;
            %}
            
            
        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
        end

        % Value changed function: ReefSubsetDropDown
        function ReefSubsetDropDownValueChanged(app, event)
            value = app.ReefSubsetDropDown.Value;
            fieldOff = false;
            keyOff = false;
            switch value
                case 'Skip (below)'
                    app.EverynreefsEditField.Enable = 'on';
                    app.EverynreefsEditFieldLabel.Enable = 'on';
                    app.modelVars.everyx = app.EverynreefsEditField.Value;
                case 'All Reefs'
                    app.modelVars.everyx = 1;
                    keyOff = true;
                case 'Only Key Reefs'
                    app.modelVars.everyx = 10000;
                case 'Equatorial'
                    app.modelVars.everyx = 'eq';                
                case 'Mid Latitude'
                    app.modelVars.everyx = 'lo';                
                case 'High Latitude'
                    app.modelVars.everyx = 'hi';
                otherwise
                    error('Reef selection dropdown field %s is unsupported!', value);
            end
            if fieldOff
                app.EverynreefsEditField.Enable = 'off';
                app.EverynreefsEditFieldLabel.Enable = 'off';
            else
                app.EverynreefsEditField.Enable = 'on';
                app.EverynreefsEditFieldLabel.Enable = 'on';
            end
            if keyOff
                app.KeyReefsTextArea.Enable = 'off';
            else
                app.KeyReefsTextArea.Enable = 'on';
            end
                
        end

        % Value changed function: WorkerthreadsSlider
        function WorkerthreadsSliderValueChanged(app, event)
            value = round(app.WorkerthreadsSlider.Value);
            app.WorkerthreadsSlider.Value = value;
            app.modelVars.useTestThreads = value;
        end

        % Selection changed function: ClimateScenarioButtonGroup
        function ClimateScenarioButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClimateScenarioButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            txt = replace(txt, '.', '');
            app.modelVars.RCP = lower(replace(txt, ' ', ''));
            
                
        end

        % Callback function
        function GoButton_2Pushed(app, event)
            setKeySaveState(app);
                    
            
            % Make the variables local and call the model!
            scriptVars = app.modelVars;
            
            % For each thread make a new slider as a progress bar.
            % consider the bar made in app developer a dummy, and hide it.
            %app.ProgressGauge_2.Visible = 'off';
            
            nw = scriptVars.useTestThreads;
            %{
            maxBarY = 300;
            y = 40;
            htOne = 40;
            htN = 26;
            dy = 50;
            if nw*dy > maxBarY
                dy = round(maxBarY / (nw+1));
                htOne = max(30, round(dy * 0.85));
                htN = min(26, round(dy * 0.85));
            end
            %}
            %fprintf('Setting up axes for nw = %d\n', nw);
            ax = app.UIAxes;
            ax.XLim = [0 nw+1];
            ax.YLim = [0 100];
            ax.XTick = 0:nw+1;
            labs = ax.XTickLabel;
            disp(labs)
            labs{1} = '';
            labs{nw+2} = '';
            ax.XTickLabel = labs;
            %disp(labs)
            
            t = timer();
            t.StartFcn = @(~, thisEvent)ProgressUpdater2(ax, nw, 0);
            t.TimerFcn = @(~, thisEvent)ProgressUpdater2(ax, nw);
            t.StopFcn = @(~, thisEvent)ProgressUpdater2(ax, nw, 100);
            t.Period = 1;
            t.TasksToExecute = 100;
            t.ExecutionMode = 'fixedRate';
            start(t);
            pause(0.1);
            %{
            for i = 1:length(app.gaugeSet)
                disp('deleting gauge');
                delete(app.gaugeSet(i));
            end
            
            for i = 1:nw
                
                g = uigauge(app.RunTab, 'linear'); 
                if i > 1
                    g.MajorTicks = [0 100];
                    g.MajorTickLabels = {'0', '100'};
                    % g.FontSize = 4;
                    g.Position = [131 y 380 htN];
                    y = y + htN + 5;
                else
                    g.Position = [131 y 380 htOne];
                    g.MajorTicks = [0 20 40 60 80 100];
                    y = y + htOne + 5;
                end
                app.gaugeSet(i) = g;
                
                t = timer();
                t.StartFcn = @(~, thisEvent)ProgressUpdater(g, i, 0);
                t.TimerFcn = @(~, thisEvent)ProgressUpdater(g, i);
                t.StopFcn = @(~, thisEvent)ProgressUpdater(g, i, 100);
                t.Period = 1;
                t.TasksToExecute = 100;
                t.ExecutionMode = 'fixedRate';
                start(t);
                timers(i) = t;
            end
            %}
            A_Coral_Model_170118
            
            stop(t);
            delete(t);
        end

        % Value changed function: OceanAcidificationCheckBox
        function OceanAcidificationCheckBoxValueChanged(app, event)
            value = app.OceanAcidificationCheckBox.Value;
            app.modelVars.OA = double(value);
        end

        % Value changed function: EvolutionCheckBox
        function EvolutionCheckBoxValueChanged(app, event)
            value = app.EvolutionCheckBox.Value;
            app.modelVars.E = value;
        end

        % Value changed function: EverynreefsEditField
        function EverynreefsEditFieldValueChanged(app, event)
            value = app.EverynreefsEditField.Value;
            if app.ReefSubsetDropDown.Value == 'Skip (below)'
                app.modelVars.everyx = value;
            else
                error('It should be impossible to enter a value when the dropdown is not set to "skip".');
            end
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)
            setKeySaveState(app);
        end

        % Selection changed function: SuperSymbiontsButtonGroup
        function SuperSymbiontsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SuperSymbiontsButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            app.modelVars.superSym = txt;
            newMortYears = false;
            switch txt
                case 'None'
                    app.modelVars.superMode = 0;
                    app.modelVars.superAdvantage = 0.0;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
                case 'On'
                    app.modelVars.superAdvantage = 0.0;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                case 'Save long mortality years'
                    app.modelVars.newMortYears = true;
                    app.modelVars.superMode = 0;
                    app.modelVars.superAdvantage = 0.0;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
                otherwise
                    error('No logic for button %s.', txt);
            end
            updateSuperFields(app);
        end

        % Value changed function: TemperatureadvantageEditField
        function TemperatureadvantageEditFieldValueChanged(app, event)
            value = app.TemperatureadvantageEditField.Value;
            app.modelVars.superAdvantage = value;
        end

        % Value changed function: AtyearEditField
        function AtyearEditFieldValueChanged(app, event)
            value = app.AtyearEditField.Value;
            app.modelVars.superStart = value;
        end

        % Selection changed function: SuperSymbiontoptionsButtonGroup
        function SuperSymbiontoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SuperSymbiontoptionsButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            switch txt
                case 'Add specified advantage'
                    app.modelVars.superMode = 0;
                case 'Use mean of superInitYears'
                    app.modelVars.superMode = 1;
                case 'Use max of superInitYears'
                    app.modelVars.superMode = 2;
                case 'Add specified advantage at end of first 5-year mortality'
                    app.modelVars.superMode = 5;
                otherwise
                    error('Super sym option selection %s is not supported in the GUI.', txt);
            end
            updateSuperFields(app);
        end

        % Value changed function: GlobalCoralCoverCheckBox
        function GlobalCoralCoverCheckBoxValueChanged(app, event)
            value = app.GlobalCoralCoverCheckBox.Value;
            app.modelVars.doCoralCoverFigure = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AbioticTab = uitab(app.TabGroup);
            app.AbioticTab.Title = 'Abiotic';
            app.AbioticTab.Units = 'pixels';
            app.ClimateScenarioButtonGroup = uibuttongroup(app.AbioticTab);
            app.ClimateScenarioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClimateScenarioButtonGroupSelectionChanged, true);
            app.ClimateScenarioButtonGroup.Title = 'Climate Scenario';
            app.RCP26Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP26Button.Text = 'RCP 2.6';
            app.RCP26Button.Value = true;
            app.RCP45Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP45Button.Text = 'RCP 4.5';
            app.RCP60Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP60Button.Text = 'RCP 6.0';
            app.RCP85Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP85Button.Text = 'RCP 8.5';
            app.OceanAcidificationCheckBox = uicheckbox(app.AbioticTab);
            app.OceanAcidificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OceanAcidificationCheckBoxValueChanged, true);
            app.OceanAcidificationCheckBox.Text = 'Ocean Acidification';
            app.BioticTab = uitab(app.TabGroup);
            app.BioticTab.Title = 'Biotic';
            app.BioticTab.Units = 'pixels';
            app.EvolutionCheckBox = uicheckbox(app.BioticTab);
            app.EvolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EvolutionCheckBoxValueChanged, true);
            app.EvolutionCheckBox.Text = 'Evolution';
            app.SuperSymbiontsButtonGroup = uibuttongroup(app.BioticTab);
            app.SuperSymbiontsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SuperSymbiontsButtonGroupSelectionChanged, true);
            app.SuperSymbiontsButtonGroup.Title = 'Super Symbionts';
            app.NoneButton = uiradiobutton(app.SuperSymbiontsButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.OnButton = uiradiobutton(app.SuperSymbiontsButtonGroup);
            app.OnButton.Text = 'On';
            app.SavelongmortalityyearsButton = uiradiobutton(app.SuperSymbiontsButtonGroup);
            app.SavelongmortalityyearsButton.Text = 'Save long mortality years';
            app.SuperSymbiontoptionsButtonGroup = uibuttongroup(app.BioticTab);
            app.SuperSymbiontoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SuperSymbiontoptionsButtonGroupSelectionChanged, true);
            app.SuperSymbiontoptionsButtonGroup.Title = 'Super Symbiont options';
            app.AddspecifiedadvantageButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.AddspecifiedadvantageButton.Text = 'Add specified advantage';
            app.AddspecifiedadvantageButton.Value = true;
            app.UsemeanofsuperInitYearsButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.UsemeanofsuperInitYearsButton.Text = 'Use mean of superInitYears';
            app.UsemaxofsuperInitYearsButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.UsemaxofsuperInitYearsButton.Text = 'Use max of superInitYears';
            app.Addspecifiedadvantageatendoffirst5yearmortalityButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.Addspecifiedadvantageatendoffirst5yearmortalityButton.Text = 'Add specified advantage at end of first 5-year mortality';
            app.SuperOptionPanel = uipanel(app.BioticTab);
            app.AtyearEditFieldLabel = uilabel(app.SuperOptionPanel);
            app.AtyearEditFieldLabel.Text = 'At year';
            app.AtyearEditField = uieditfield(app.SuperOptionPanel, 'numeric');
            app.AtyearEditField.ValueChangedFcn = createCallbackFcn(app, @AtyearEditFieldValueChanged, true);
            app.AtyearEditField.Limits = [1861 2099];
            app.AtyearEditField.ValueDisplayFormat = '%.0f';
            app.AtyearEditField.Value = 2035;
            app.TemperatureadvantageEditFieldLabel = uilabel(app.SuperOptionPanel);
            app.TemperatureadvantageEditFieldLabel.Text = 'Temperature advantage';
            app.TemperatureadvantageEditField = uieditfield(app.SuperOptionPanel, 'numeric');
            app.TemperatureadvantageEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureadvantageEditFieldValueChanged, true);
            app.TemperatureadvantageEditField.Limits = [-3 10];
            app.TemperatureadvantageEditField.ValueDisplayFormat = '%11.2g';
            app.ProgrammaticTab = uitab(app.TabGroup);
            app.ProgrammaticTab.Title = 'Programmatic';
            app.ProgrammaticTab.Units = 'pixels';
            app.EverynreefsEditFieldLabel = uilabel(app.ProgrammaticTab);
            app.EverynreefsEditFieldLabel.Text = 'Every n reefs';
            app.EverynreefsEditField = uieditfield(app.ProgrammaticTab, 'numeric');
            app.EverynreefsEditField.ValueChangedFcn = createCallbackFcn(app, @EverynreefsEditFieldValueChanged, true);
            app.EverynreefsEditField.Limits = [1 1925];
            app.EverynreefsEditField.Value = 1;
            app.ReefSubsetDropDownLabel = uilabel(app.ProgrammaticTab);
            app.ReefSubsetDropDownLabel.Text = 'Reef Subset';
            app.ReefSubsetDropDown = uidropdown(app.ProgrammaticTab);
            app.ReefSubsetDropDown.Items = {'All Reefs', 'Only Key Reefs', 'Equatorial', 'Mid Latitude', 'High Latitude', 'Skip (below)'};
            app.ReefSubsetDropDown.ValueChangedFcn = createCallbackFcn(app, @ReefSubsetDropDownValueChanged, true);
            app.ReefSubsetDropDown.Value = 'All Reefs';
            app.KeyReefsTextAreaLabel = uilabel(app.ProgrammaticTab);
            app.KeyReefsTextAreaLabel.Text = 'Key Reefs';
            app.KeyReefsTextArea = uitextarea(app.ProgrammaticTab);
            app.KeyReefsTextArea.Value = {'144 402 420 793 1541'};
            app.RunevenifnotspecifiedaboveLabel = uilabel(app.ProgrammaticTab);
            app.RunevenifnotspecifiedaboveLabel.Text = 'Run even if not specified above';
            app.WorkerthreadsSliderLabel = uilabel(app.ProgrammaticTab);
            app.WorkerthreadsSliderLabel.Text = 'Worker threads';
            app.WorkerthreadsSlider = uislider(app.ProgrammaticTab);
            app.WorkerthreadsSlider.Limits = [1 15];
            app.WorkerthreadsSlider.ValueChangedFcn = createCallbackFcn(app, @WorkerthreadsSliderValueChanged, true);
            app.WorkerthreadsSlider.Value = 3;
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputsTab.Units = 'pixels';
            app.GlobalMapsCheckBox = uicheckbox(app.OutputsTab);
            app.GlobalMapsCheckBox.Text = 'Global Maps';
            app.GlobalCoralCoverCheckBox = uicheckbox(app.OutputsTab);
            app.GlobalCoralCoverCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalCoralCoverCheckBoxValueChanged, true);
            app.GlobalCoralCoverCheckBox.Text = 'Global Coral Cover';
            app.PanelPopulationPlotsButtonGroup = uibuttongroup(app.OutputsTab);
            app.PanelPopulationPlotsButtonGroup.Title = '4-Panel Population Plots';
            app.AllreefsButton = uiradiobutton(app.PanelPopulationPlotsButtonGroup);
            app.AllreefsButton.Text = 'All reefs';
            app.KeyReefsButton = uiradiobutton(app.PanelPopulationPlotsButtonGroup);
            app.KeyReefsButton.Text = 'Key Reefs';
            app.KeyReefsButton.Value = true;
            app.OffButton = uiradiobutton(app.PanelPopulationPlotsButtonGroup);
            app.OffButton.Text = 'Off';
            app.MortalityandothertablesCheckBox = uicheckbox(app.OutputsTab);
            app.MortalityandothertablesCheckBox.Text = 'Mortality and other tables';
            app.SavedataforcrossrunstressedreefplotsCheckBox = uicheckbox(app.OutputsTab);
            app.SavedataforcrossrunstressedreefplotsCheckBox.Text = 'Save data for cross-run stressed-reef plots';
            app.GenotypefigureCheckBox = uicheckbox(app.OutputsTab);
            app.GenotypefigureCheckBox.Text = 'Genotype figure';
            app.GrowthratefigureCheckBox = uicheckbox(app.OutputsTab);
            app.GrowthratefigureCheckBox.Text = 'Growth rate figure';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.RunTab.Units = 'pixels';
            app.GoButton_2 = uibutton(app.RunTab, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.CheckSelectionsTextAreaLabel = uilabel(app.RunTab);
            app.CheckSelectionsTextAreaLabel.Text = 'Check Selections';
            app.CheckSelectionsTextArea = uitextarea(app.RunTab);
            app.UIAxes = uiaxes(app.RunTab);
            title(app.UIAxes, 'Progress');
            xlabel(app.UIAxes, 'Worker');
            ylabel(app.UIAxes, 'Percent Complete');
            app.JSONformatLabel = uilabel(app.RunTab);
            app.JSONformatLabel.Text = '(JSON format)';
            app.SaveStateButton = uibutton(app.RunTab, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Text = 'Save State';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ModelGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end